classdef Results1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SimulationTab                   matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        RefreshButton                   matlab.ui.control.Button
        ParametersPanel_2               matlab.ui.container.Panel
        NoiseIntensitySliderLabel       matlab.ui.control.Label
        NoiseIntensitySlider            matlab.ui.control.Slider
        BandwidthLabel                  matlab.ui.control.Label
        BandwidthSlider                 matlab.ui.control.Slider
        UIAxes_2                        matlab.ui.control.UIAxes
        SelectthefrequencyintervalLabel  matlab.ui.control.Label
        PeakParametersLabel             matlab.ui.control.Label
        SinewaveparametersLabel         matlab.ui.control.Label
        MHzLabel                        matlab.ui.control.Label
        MHzLabel_2                      matlab.ui.control.Label
        IntervalAEditFieldLabel         matlab.ui.control.Label
        IntervalAEditField              matlab.ui.control.NumericEditField
        IntervalBEditFieldLabel         matlab.ui.control.Label
        IntervalBEditField              matlab.ui.control.NumericEditField
        PeriodEditFieldLabel            matlab.ui.control.Label
        PeriodEditField                 matlab.ui.control.NumericEditField
        MeanEditFieldLabel              matlab.ui.control.Label
        MeanValueEdit                   matlab.ui.control.NumericEditField
        StandardDeviationEditFieldLabel  matlab.ui.control.Label
        StandardDeviationEdit           matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel         matlab.ui.control.Label
        AmplitudeEdit                   matlab.ui.control.NumericEditField
        AddParametersButton             matlab.ui.control.Button
        CorrelationButton               matlab.ui.control.Button
        SimulationPadPanel              matlab.ui.container.Panel
        SimulateButton                  matlab.ui.control.Button
        ChemicalSelectionListBoxLabel   matlab.ui.control.Label
        ChemicalSelectionListBox        matlab.ui.control.ListBox
        SimulationNumberEditFieldLabel  matlab.ui.control.Label
        SimulationNumber                matlab.ui.control.NumericEditField
        ParametersSelectionListBoxLabel  matlab.ui.control.Label
        ParametersSelectionListBox      matlab.ui.control.ListBox
        AutogenerateButton              matlab.ui.control.Button
        ChemicalSelectionPanel          matlab.ui.container.Panel
        ChemicalSelectionButton         matlab.ui.control.Button
        ImportButton                    matlab.ui.control.Button
        ChemicalListBoxLabel            matlab.ui.control.Label
        ChemicalListBox                 matlab.ui.control.ListBox
        CorrelationTab                  matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes2_2                       matlab.ui.control.UIAxes
        SimulationPadPanel_2            matlab.ui.container.Panel
        ChemicalSelectionLabel          matlab.ui.control.Label
        ChemicalSelectionListBox2       matlab.ui.control.ListBox
        GroundTruthSelectionLabel       matlab.ui.control.Label
        GTSelectionListBox              matlab.ui.control.ListBox
        CorrelationPeaks                matlab.ui.control.Button
        RefreshButton_2                 matlab.ui.control.Button
        ReportButton                    matlab.ui.control.Button
        SelectionPanel                  matlab.ui.container.Panel
        AddButton_2                     matlab.ui.control.Button
        JPLGroundTruthListBoxLabel      matlab.ui.control.Label
        JPLGroundTruthListBox           matlab.ui.control.ListBox
        AddButton                       matlab.ui.control.Button
        ChemicalListBox_2Label          matlab.ui.control.Label
        ChemicalListBox2                matlab.ui.control.ListBox
    end

 properties (Access = private)
        Property % Description
        Data1
        Data2
        Data3
        Frequency
        Intensity
        f
        title
        ax
        xdata
        ydata
        x1data
        y1data
        y2data
        x2data
        x_upsampled
        y_upsampled
        x2_upsampled
        y2_upsampled
        peak_shape
        A %Data import handler
        pk
        lk
        noise_intensity
        Interval_A
        Interval_B
        edt
        value
        filename
        filepath
        directory
        listboxvalue 
        listboxvalue2
        Import
        period
        Datanumber
        sample
        fName
        List
        mydirectory
        po
        so
        po2
        so2
        po3
        so3
        Mean
        Std
        Amp
        ChemicalList %Tab 1 Chemical List box for selection
        ChemicalList2
        ChemicalSelection%Tab 1 Chemicals selected in the listbox
        ChemicalListtab2 % Tab 2 Chemical Listbox containers
        ChemicalListtab22
        ChemicalSelectionListBoxtab2 
        JPLGTListBoxtab2  % Tab 2 JPL Ground Truth containers for listbox selection
        JPLGTList
        JPlGTList2
        JPLlistboxvalue
        ParameterSelection
        Parameters
        Slistboxvalue
        Slistboxvalue2
        Plistboxvalue
        Plistboxvalue2
        img1
        img2
        NBW
        NBW2
        FRange
        FRange2
        Delta_F
        Delta_F2
        Intensity_T1
        Frequency_T1
        Intensity_T
        Frequency_T
        Intensity_T2
        Frequency_T2
        y1dataFiltered
        y2dataFiltered
        temp
        ThresholdGT
        ThresholdChemicals 
        F1Name
        Table
        JPLGroundTruth
        JPLFilesList
      
 end

    methods (Access = private)
        function SortingValues(app)
            
            disp('  Sorting Values...')
            app.ydata = app.ydata(app.xdata>=app.Interval_A & app.xdata<=app.Interval_B);
            app.xdata = app.xdata(app.xdata>=app.Interval_A & app.xdata<=app.Interval_B);
            app.ydata = exp(app.ydata);
            
            [app.xdata, idx_sort] = sort(app.xdata);
            app.ydata = app.ydata(idx_sort);
        end
           
        function createWaveFormAndPlot1(app)
        
            % now change the random noise associated with the peak intensity
            
            app.y2data = app.ydata .* ((app.Mean)+randn(size(app.ydata)).*(app.Std));
            
            app.x_upsampled=app.Interval_A:0.0488:app.Interval_B;
            app.y_upsampled=zeros(size(app.x_upsampled));
%             app.xdata=app.xdata-((rand(size(app.xdata))-0.5)/10);
            %based on observation change is less than 0.1MHz so we can even lower the difference on the interval of rand.
            
            for i= 1:length(app.xdata)
                idx=find(app.x_upsampled<app.xdata(i),1,'last');
                app.y_upsampled(idx)=app.y2data(i);
            end
            
            pan(app.UIAxes,'on');
            zoom(app.UIAxes,'on');
            
           % disp('  Applying Convolution...');
            app.y_upsampled = conv(app.y_upsampled,app.peak_shape,'same');
            app.y_upsampled = 110*app.y_upsampled + ...   bring intensity to same as CMOS arbitrary units (empirically)
            app.Amp*sin(2*pi*1/((app.period)*10^5)*app.x_upsampled).*sin(2*pi*1/(0.04*10^5)*app.x_upsampled)+0.1 + ... add sine wave oscillation apparent in example file (observed period and range from one file)
            randn(size(app.y_upsampled))*app.noise_intensity; % add in some white Gaussian noise 
% 
%             
%             hold(app.UIAxes_3,'off');
%             plot(app.UIAxes_3,app.x_upsampled,app.y_upsampled,'red')
%             pan(app.UIAxes_3,'on');
%             zoom(app.UIAxes_3,'on');
%             hold(app.UIAxes_3,'on');
%            app.SampleSizeLabelRaw.Text = sprintf('Sample Size: %dx%d',size(app.x_upsampled));
           
 
           app.noise_intensity=[app.noise_intensity;nan(abs(size(app.x_upsampled,2)-size(app.noise_intensity,2)),1)];
           app.Mean=[app.Mean;nan(abs(size(app.x_upsampled,2)-size(app.Mean,2)),1)];
           app.Std=[app.Std;nan(abs(size(app.x_upsampled,2)-size(app.Std,2)),1)];
           app.Amp=[app.Amp;nan(abs(size(app.x_upsampled,2)-size(app.Amp,2)),1)];
           
           i=app.sample+1;
           T{i}=table(app.x_upsampled.',app.y_upsampled.',app.noise_intensity,app.Mean,app.Std,app.Amp,'VariableNames',{'Frequency','Intensity','Noise','Mean','StandardDeviation','Amplitude'});
         %  Dat{i}=table({'Filename=';'Mean=';'Standard_Deviation=';'Amplitude=';'Noise_Intensity=';'Interval_A='; 'Interval_B='},[{app.List};app.Mean(1,1);app.Std(1,1);app.Amp;app.noise_intensity(1,1);app.Interval_A;app.Interval_B]);
           app.mydirectory=(['C:\Users\46701256\Box\RSCMOS\Results\' app.List '\']);  
           
           T=T{i};
        
           j=0;
           FileName=[app.mydirectory app.List num2str(j) '.mat'];
           
           while exist(FileName,'file')==2
               j=j+1;
               FileName=[app.mydirectory app.List num2str(j) '.mat'];
               %DataFileName=[app.mydirectory app.List '-' dat num2str(j)];
           end
%            DataFileName=[app.mydirectory app.List '-' dat num2str(j)];
%          %  writetable(Dat{i},DataFileName,'delimiter',' ','WriteVariableNames',false);
         
            save(FileName,'T','-mat');

            disp('File generated')
      end
        
        
        function updatePeakWaveform(app)
            peak = app.peak_shape;
            hold(app.UIAxes_2,'off');
            plot(app.UIAxes_2,peak,'k-');
            pan(app.UIAxes_2,'on');
            zoom(app.UIAxes_2,'on');
            xlim(app.UIAxes_2,[0,2000]);
            hold(app.UIAxes_2,'on');
        end


        function peak_shape = createPeakshape(app, sigma)
             x = -2*pi:1.0/160.0:2*pi;
            peak_shape = -(2*x.^2 - 2*sigma^2) ./ sigma.^4 .* exp(-x.^2/(2*sigma^2));
            peak_shape = peak_shape ./ max(peak_shape);
            
        end
        
        function [xdata, ydata] = load_file(app,filenames,List)
            
            xdata = [];
            ydata = [];
            x=[];
            y=[];
            xpre=[];
            ypre=[];
            
            app.List='';
               path='C:\Users\46701256\Box\RSCMOS\JPL Ground Truth';
            for i=1:(length(filenames)-1)
                filename = filenames{i};
                [~,app.fName,ext]=fileparts(filename);
                file=fullfile(path, filename);
                fileID=fopen(file);
                c=textscan(fileID,'%f %f %f %*[^\n]');
                
                x=cell2mat(c(:,1));
                y=cell2mat(c(:,3));
                
                %disp(['Loading ', filename]);
                
               % data{i}=cell2mat
                
                
                app.List=[app.fName,'', app.List];
                fclose(fileID);
                xdata=[xdata;x];
                ydata=[ydata;y];
             end
       mkdir (['C:\Users\46701256\Box\RSCMOS\Results\' app.List '\']);
            
         %   disp('Done Loading');
            
           % app.DataIntervalLabel.Text = sprintf("Data interval: %.2f MHz-%.2f MHz",min(xdata),max(xdata));
            
            if  app.Interval_A==0 ||app.Interval_B==0
                uialert(app.UIFigure,'choose the frequency interval','interval');
                app.refreshplot()
                return
            end


        end
        
        function [x1data, y1data, noise_intensity, Std, Mean]=load_filetab2(app,filenames,List) %Load files for Tab 2
            x1data = [];
            y1data = [];
            noise_intensity=[];
            Std=[];
            Mean=[];
            
            app.List='';
            
            path='C:\Users\46701256\Box\RSCMOS\Chemical Mixes\';
            
            for i=1:(length(filenames)-1)
                filename = filenames{i};
                [~,app.fName,ext]=fileparts(filename);
               % disp(['Loading ', filename]);
                
                Fullloadedfile=fullfile(path,filename); %Construct the path and filename together for laoding
%                 Atmp=dlmread(fullfile(path,filename),'',1,0);
                load(Fullloadedfile);
           
                x1data=[x1data;T(:,1)];
                y1data=[y1data;T(:,2)];
                
%                 x1data=[T(:,1)];
%                 y1data=[T(:,2)];
%                 
                noise_intensity=[noise_intensity;T(1,3)];
                Mean=[Mean;T(1,4)];
                Std=[Std;T(1,5)];
                app.List=[app.fName,'', app.List];


            end
            %mkdir (['C:\Users\46701256\Desktop\Project Data\Results\' app.List '\']);
            
            %disp('Done Loading');
            
            
            if  app.Interval_A==0 ||app.Interval_B==0
                uialert(app.UIFigure,'choose the frequency interval','interval');
                app.refreshplot()
                return
            end
            
            
        end
    
        function loadparameters(app,params)
             
            app.Interval_A = table2array(params(:,'Interval_A'));
            app.Interval_B = table2array(params(:,'Interval_B'));
            app.noise_intensity=table2array(params(:,'Noise'));
            app.Mean= table2array(params(:,'Mean'));
            app.Std= table2array(params(:,'STD'));
            app.Amp= table2array(params(:,'Amp'));
            app.period =  table2array(params(:,'Period'));
         
                      
        end


%         function [x2data, y2data]=load_filetab22(app,filenames,List)
%             
%             x2data = [];
%             y2data = [];
%             app.List='';
%             
%         path='C:\Users\46701256\Desktop\Project Data\JPL Ground Truth\';
%          
%         for i=1:(length(filenames)-1)
% %                 filename = filenames{i};
% %                 [~,app.F1Name,ext]=fileparts(filename);
% %                 %disp(['Loading ', filename]);
% %             %    Atmp=dlmread(fullfile(path,filename),'',2,0);
% %                 file=fullfile(path,filename);
% %                 fileID=fopen(file);
% %                 c=textscan(fileID,'%f %f %f %f %f %f %*[^\n]');
% %                 %Atmp=dlmread(c);
% %                 x2data=cell2mat(c(:,1));
% %                 y2data=cell2mat(c(:,3));
% %                 app.List=[app.F1Name,'', app.List];
% %                 fclose(fileID);
%         end
%       % mkdir (['C:\Users\46701256\Desktop\Project Data\Results\' app.List '\']);
%             
%            % disp('Done Loading');
%             
%             
%             if  app.Interval_A==0 ||app.Interval_B==0
%                 uialert(app.UIFigure,'choose the frequency interval','interval');
%                 app.refreshplot()
%                 return
%             end
%             
%             
%         end
        
        function index = cell2index(app,cells,string)
            
            index = ~cellfun('isempty',strfind(cells,string));
            
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            app.directory='C:\Users\46701256\Box\RSCMOS\JPL Ground Truth';
            Infolder=dir(app.directory);
            MyListOfFiles=[];
            MyListOfFiles={Infolder(~[Infolder.isdir]).name};
           
            set(app.ChemicalListBox,'Items',MyListOfFiles); %Setting the chemical list box from the folder
            
            app.directory='C:\Users\46701256\Box\RSCMOS\Chemical Mixes';
            Infolder=dir(app.directory);
            MyListOfFiles=[];
            MyListOfFiles={Infolder(~[Infolder.isdir]).name};
            set(app.ChemicalListBox2,'Items',MyListOfFiles); %Setting the Chemical Mix list box on tab2
          
            app.directory='C:\Users\46701256\Box\RSCMOS\JPL Ground Truth';
            Infolder=dir(app.directory);
            app.JPLFilesList=[];
            app.JPLFilesList={Infolder(~[Infolder.isdir]).name};
            set(app.JPLGroundTruthListBox,'Items',app.JPLFilesList); %Setting the JPL Ground truth list box on tab2
            
            app.JPLGroundTruth = {};
            for name = app.JPLFilesList
                
                file=fullfile(app.directory, name{1});
                fileID=fopen(file);
                c=textscan(fileID,'%f %f %f %*[^\n]');
                
                xdata=cell2mat(c(:,1));
                ydata=cell2mat(c(:,3));
                
                app.JPLGroundTruth{end+1} = [xdata, ydata];
            end
            
            
            app.peak_shape = createPeakshape(app, 1/12);
            app.noise_intensity = 0;
            app.Interval_A=500;
            app.Interval_B=400000;
            app.Mean=1;
            app.Std=0;
            app.Amp=0;
            app.period=0.0004;
            app.po=1;
            app.po2=1;
            app.po3=1;
            app.so=1;
            app.so2=1;
            app.so3=1;
            cla(app.UIAxes);
            cla(app.UIAxes_2);
            cla(app.UIAxes_3);
            hold(app.UIAxes,'on');
            hold(app.UIAxes_2,'on');
            hold(app.UIAxes_3,'on');
        end

        % Button pushed function: RefreshButton, RefreshButton_2
        function refreshplot(app, event)
            
            set(app.ChemicalListBox, 'Value', {});
            set(app.ChemicalListBox2, 'Value', {});
            
            set(app.ChemicalSelectionListBox2, 'Value', {});
            set(app.ChemicalSelectionListBox2, 'Items', {});
            set(app.GTSelectionListBox, 'Value', {});
            set(app.GTSelectionListBox, 'Items', {});
            
            cla(app.UIAxes)
            cla(app.UIAxes_2)
            cla(app.UIAxes_3)
            cla(app.UIAxes2_2)
            cla(app.UIAxes2)
            
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            delete(app)
            
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            
            [file,path] = uigetfile({'*.txt';'*.m';'*.slx';'*.mat';'*.*';'*.cat';});
            if isequal(file,0)
                %disp('User selected Cancel');
            else
                %disp(['User selected ', fullfile(path,file)]);
                app.Import=dlmread(fullfile(path,file),'',2,0);
            end
            app.x1data= [];
            app.y1data = [];
          
            if size(app.Import,2) > 3
                app.x1data=app.Import(:,1);
                app.y1data=app.Import(:,3);
                app.DataIntervalLabel.Text = sprintf("Data interval: %.2f MHz-%.2f MHz",app.x1data(1,1),app.x1data(end,1));
                plot(app.UIAxes,app.x1data,app.y1data,'green');
               pan(app.UIAxes,'on');
            zoom(app.UIAxes,'on');
            else
                app.x1data=app.Import(:,1);
                app.y1data=app.Import(:,2);
                plot(app.UIAxes,app.x1data,app.y1data,'b');
                 pan(app.UIAxes,'on');
            zoom(app.UIAxes,'on');
            end
            xlim(app.UIAxes,[ min(app.x1data), max(app.x1data)]); 
            %app.DataIntervalLabel.Text = sprintf("Data interval: %.2f MHz-%.2f MHz",app.x1data(1,1),app.x1data(end,1));
            %app.SampleSizeLabel.Text = sprintf('Sample Size: %dx%d',size(app.x1data));


        end

        % Value changed function: NoiseIntensitySlider
        function NoiseIntensitySliderValueChanged(app, event)
            app.noise_intensity = app.NoiseIntensitySlider.Value;
            app.updatePeakWaveform();
         
            
        end

        % Value changed function: BandwidthSlider
        function BandwidthSliderValueChanged(app, event)
            value = app.BandwidthSlider.Value;
            app.peak_shape = createPeakshape(app, 1 / value);
            app.updatePeakWaveform();
        end

        % Value changed function: AmplitudeEdit, 
        % IntervalAEditField, IntervalBEditField, MeanValueEdit, 
        % PeriodEditField, StandardDeviationEdit
        function ParametersValueChanged(app, event)
           
          
            app.Interval_A = app.IntervalAEditField.Value;
            app.Interval_B = app.IntervalBEditField.Value;
            app.noise_intensity = app.NoiseIntensitySlider.Value;
            app.Mean=app.MeanValueEdit.Value;
            app.Std=app.StandardDeviationEdit.Value;
            app.Amp=app.AmplitudeEdit.Value;
            app.period = app.PeriodEditField.Value;
            
            
            app.Parameters{app.so}=table(app.Interval_A,app.Interval_B,app.noise_intensity,app.Mean,app.Std, app.Amp,app.period ,'VariableNames',{'Interval_A','Interval_B','Noise','Mean','STD','Amp','Period'});
%             app.BandwidthSliderValueChnaged();
%             app.NoiseIntensitySliderValueChanged();
            app.updatePeakWaveform();
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            app.Slistboxvalue= app.ChemicalSelectionListBox.Value;
            filenames=strsplit(app.Slistboxvalue);
            
            [app.xdata, app.ydata] = app.load_file(filenames);
            app.SortingValues();
            
            app.Plistboxvalue=app.ParametersSelectionListBox.Value;
            [~, index] = ismember(app.ParametersSelectionListBox.Value, app.ParametersSelectionListBox.Items);
            
%            table2struct([app.Parameters{index} 
%            load(app.Parameters{index});
             app.loadparameters(app.Parameters{index});
             app.Datanumber=app.SimulationNumber.Value;
           for i=0:(app.Datanumber-1)
             app.sample=i;
            
            app.createWaveFormAndPlot1();
            %disp(['  Done with sample ', num2str(i+1)])
            
           end
         
        end

        % Button pushed function: CorrelationButton
        function CorrelationButtonPushed(app, event)
            d2=interp1(1:numel(app.ydata),app.ydata,linspace(1,numel(app.ydata),numel(app.y1data)));
            
            R=corrcoef(d2,app.y1data);
            
            ax=[];
            ax(end+1)=subplot(2,1,1);
            plot(app.x1data,app.y1data)
            xlabel('Frequency MHz');
            ylabel('Intensity (Arb unit)');
            title('Sample Data');
            
            ax(end+1)=subplot(2,1,2);
            plot(app.x_upsampled,app.y_upsampled)
            xlabel('Frequency MHz');
            ylabel('Intensity (Arb unit)');
            title([app.title]);
            
            linkaxes(ax,'x');
        end

        % Button pushed function: ChemicalSelectionButton
        function ChemicalSelectionButtonPushed(app, event)
        
            app.listboxvalue= app.ChemicalListBox.Value;
            app.ChemicalList={};
           for i=1:length(app.listboxvalue)
                app.ChemicalList=[app.listboxvalue{i},' ',app.ChemicalList];    
                i=i+1;
           end
          
          app.ChemicalList2{app.so}=table(app.po,app.ChemicalList,'VariableNames',{'po','names'});
          app.ChemicalSelection{app.po}=([app.ChemicalList{:}]);


           app.ChemicalSelectionListBox.Items{app.po}=app.ChemicalSelection{app.po};
           app.po=app.po+1;
        end

        % Button pushed function: AddParametersButton
        function AddParametersButtonPushed(app, event)
     
           app.ParameterSelection{app.so}=(['Intervals: ',int2str(app.Interval_A),'-',num2str(app.Interval_B),' MHz ,Noise: ', num2str(app.noise_intensity,'%.3f'), ' ,Mean:' num2str(app.Mean), ' ,STD:' num2str(app.Std)]);
           app.ParametersValueChanged(); 
           app.ParametersSelectionListBox.Items{app.so}=app.ParameterSelection{app.so};
           app.so=app.so+1;
        end

        % Button pushed function: AutogenerateButton
        function AutogenerateButtonPushed(app, event)
            numberchemicals=length(app.ChemicalSelection);
            numberparam=length(app.ParameterSelection);
           % n2=numberchemicals*numberparam;
            
           for d=1:(length(app.ChemicalSelection))
               app.Slistboxvalue=app.ChemicalSelection{d};
               
               filenames=strsplit(app.Slistboxvalue);
            
               [app.xdata, app.ydata] = app.load_file(filenames);
               app.SortingValues();
                for z=1:(length(app.ParameterSelection))
                    app.Plistboxvalue=app.ParameterSelection{z};
                    [~, index] = ismember(app.ParameterSelection{z}, app.ParametersSelectionListBox.Items);
                     app.loadparameters(app.Parameters{index});
                     app.Datanumber=app.SimulationNumber.Value;
                        for i=0:(app.Datanumber-1)
                            app.sample=i;
                            %iteration=
                            app.createWaveFormAndPlot1();
                            %disp(['  Done with sample ', app.ChemicalSelection{d},num2str(i+1),'-',num2str(z)])
                           
                        end
                        z=z+1;
                        app.noise_intensity=0;
                        disp('Done with Chemical')
                end
                d=d+1;
                disp('Done with Chemical')
                
           end
            disp('End of the simulation')
        end

        % Callback function
        function ImportButtonPeaksPushed(app, event)
            [file,path] = uigetfile({'*.txt';'*.m';'*.slx';'*.mat';'*.*';'*.cat';});
            if isequal(file,0)
                %   disp('User selected Cancel');
            else
                %disp(['User selected ', fullfile(path,file)]);
                app.Import=dlmread(fullfile(path,file),'',2,0);
            end
            app.x1data= [];
            app.y1data = [];
            % FRange was calculated by observing two close peaks on the sample data and the range of frequency we think the peak finding should be calculated on.
            
            app.FRange=2; %MHz
            app.Delta_F=0.0488; %Calculated by observation of sample rate in Ivan's sample data
            app.NBW=floor(app.FRange/app.Delta_F);
            
            %
            %
            if size(app.Import,2) > 3
                app.x1data=app.Import(:,1);
                app.y1data=app.Import(:,3);
                
            else
                app.x1data=app.Import(:,1);
                app.y1data=app.Import(:,2);
            end
            
            [b, a]=butter(3,[0.001 0.01],'stop');
            app.y1dataFiltered = filtfilt(b,a,app.y1data);
            
            %disp('BANDSTOP FILTER APPLIED  ');
            
            
            [b, a]=butter(3,0.04,'low');
            app.y1dataFiltered = filtfilt(b,a,app.y1dataFiltered);


            ngh=strel('line',app.NBW,90);
            app.img1=imdilate(app.y1dataFiltered,ngh);
            
            
            Top10=max(app.y1dataFiltered)*1/100; %creating a threshold based on the 10% of the maximum of the maximum peak.
            idx=( app.y1dataFiltered==app.img1 & app.y1dataFiltered>Top10);


            newThresh=mean(app.y1dataFiltered(idx))+std(app.y1dataFiltered(idx));
            
            
            idx=( app.y1dataFiltered==app.img1 & app.y1dataFiltered>newThresh);
            
            app.Frequency_T1=app.x1data(idx);
            app.Intensity_T1=app.y1dataFiltered(idx);


            pan(app.UIAxes2,'on');
            zoom(app.UIAxes2,'on');
            
            plot(app.UIAxes2,app.x1data,app.y1data,'b');
            hold(app.UIAxes2,'on');
            plot(app.UIAxes2,app.x1data,app.y1dataFiltered,'r');
            
            plot(app.UIAxes2,app.Frequency_T1, app.Intensity_T1,'k*');
            
            xlim(app.UIAxes2,[ min(app.x1data), max(app.x1data)]);
            hold(app.UIAxes2,'off');
            
        end

        % Callback function
        function ImportButtonPeaks_2Pushed(app, event)
             [file,path] = uigetfile('*.*');
            if isequal(file,0)
                %disp('User selected Cancel');
            else
               % disp(['User selected ', fullfile(path,file)]);
                app.Import=dlmread(fullfile(path,file),'',2,0);
            end
            app.x2data= [];
            app.y2data = [];
          
            app.FRange=2; %MHz
            app.Delta_F=0.0488; %Calculated by observation of sample rate in Ivan's sample data
            app.NBW=floor(app.FRange/app.Delta_F);


            if size(app.Import,2) > 3
                app.x2data=app.Import(:,1);
                app.y2data=app.Import(:,3);

            else
                app.x2data=app.Import(:,1);
                app.y2data=app.Import(:,2);
              
            end
            
            
          %  disp('  Sorting Values...')
            app.y2data = app.y2data(app.x2data>=app.Interval_A & app.x2data<=app.Interval_B);
            app.x2data = app.x2data(app.x2data>=app.Interval_A & app.x2data<=app.Interval_B);
            app.y2data = exp(app.y2data);
            
            app.x2_upsampled=app.Interval_A:0.0488:app.Interval_B;
            app.y2_upsampled=zeros(size(app.x2_upsampled));
            
            for i= 1:length(app.x2data)
                idx=find(app.x2_upsampled<app.x2data(i),1,'last');
                app.y2_upsampled(idx)=app.y2data(i);
            end
            
            
            Top10=max(app.y2_upsampled)*10/100; %creating a threshold based on the 10% of the maximum of the maximum peak.
            
            
               ngh=strel('line',app.NBW,90);
               app.img2=imdilate(app.y2_upsampled,ngh);
               
               idx=(app.y2_upsampled==app.img2 & app.y2_upsampled>Top10);
                
               app.Frequency_T=app.x2_upsampled(idx);
               app.Intensity_T=app.y2_upsampled(idx);
               
               pan(app.UIAxes2_2,'on');
               zoom(app.UIAxes2_2,'on');
            
               
               plot(app.UIAxes2_2,app.x2_upsampled, app.y2_upsampled,'b');
               hold(app.UIAxes2_2,'on');
               plot(app.UIAxes2_2,app.Frequency_T, app.Intensity_T,'k*');
               %disp('  Done Plotting')
                
               xlim(app.UIAxes2_2,[ min(app.x2data), max(app.x2data)]); 
          
               T=table(app.Frequency_T.',app.Intensity_T.','VariableNames',{'Frequency','Intensity',});
             %  disp('  Table Created!')
        end

        % Button pushed function: CorrelationPeaks
        function CorrelationPeaksPushed(app, event)
              %import mlreportgen.report.*
              %import mlreportgen.dom.*
              TP=0;
              False=0;
              TP=0;
              False=0;
              
              %varNames={'ChemicalSample','GroundTruth','NoiseIntensity','StandardDeviation','Mean','Match1','Match2','Match3','Match4','Match5'}; 
              varNames2={'ChemicalSample','GroundTruth','NoiseIntensity','StandardDeviation','Mean','Thresh1','Match1','Thresh2','Match2','Thresh3','Match3','Thresh4','Match4','Thresh5','Match5','Predicted','offset','Total_File_Peaks','JPL_Peaks'};
              %T2 = table('VariableNames',varNames,'Size', [0, length(varNames)], 'VariableType', {'string','string','double','double','double','double','double','double','double','double'});
              T3 = table('VariableNames',varNames2,'Size', [0, length(varNames2)], 'VariableType',{'string','string','string','string','string','string','double','string','double','string','double','string','double','string','double','double','string','string','string'});
              pred=0;
              intensity=0:0.1:0.3;
                
              pctg1=0;
              pctg2=0;
              pctg3=0;
              pctg4=0;
              pctg5=0;
              
            %fileID2=fopen('output.txt','a');
            
            for d=1:(length(app.ChemicalSelectionListBoxtab2))
               app.Slistboxvalue2=app.ChemicalSelectionListBoxtab2{d};
               
               filenames=strsplit(app.Slistboxvalue2);
               
               [app.x1data, app.y1data, app.noise_intensity, app.Std, app.Mean] = app.load_filetab2(filenames);
               
                 app.noise_intensity=table2array(app.noise_intensity); %Table cannot read from table, needs to convert to array.
                 app.Mean=table2array(app.Mean);
                 app.Std=table2array(app.Std);


               disp(sprintf('Testing %s======',app.fName))
                
             %  app.y1data=filloutliers(app.y1data,fillmethod);
               
               app.FRange=10; %MHz
               app.Delta_F=0.0488; %Calculated by observation of sample rate in Ivan's sample data
               app.NBW=floor(app.FRange/app.Delta_F);
               
               [b, a]=butter(3,[0.001 0.01],'stop');
               app.y1data=table2array(app.y1data);
               app.x1data=table2array(app.x1data);
               app.y1dataFiltered = filtfilt(b,a,double(app.y1data));
               
               %disp('BANDSTOP FILTER APPLIED  ');
               
               
               [b, a]=butter(3,0.04,'low');
               app.y1dataFiltered = filtfilt(b,a,app.y1dataFiltered);
               
               ngh=strel('line',app.NBW,90);
               app.img1=imdilate(app.y1dataFiltered,ngh);


              % app.ThresholdChemicals = app.ThresholdEditField.Value;
               %Top10=median(app.y1dataFiltered);
              % Top10=max(app.y1dataFiltered)*1/100; %creating a threshold based on the 10% of the maximum of the maximum peak.
               %idx=( app.y1dataFiltered==app.img1 & app.y1dataFiltered>Top10);
               
               %newThresh=mean(app.y1dataFiltered(idx))-std(app.y1dataFiltered(idx));
               
               % first get only peaks in the data
               
               % we want to measure the bandwidth of each peak
               %  15 samples seems to be about right for getting bandwidth
               %  so measure the differences in raw-raw(15 over)
               %  from sample file, good peaks are about 0.05 above, 
               %  so to be safe we set the difference to 0.1 (to be conservative)
               offset_y1 = app.y1dataFiltered(16:end);
               offset_y1 =[offset_y1;zeros(15,1)];
           
               offset_value=0.005; %0.005 initially
               
               idx=(app.y1dataFiltered==app.img1) & (app.y1dataFiltered-offset_y1)>offset_value;%& app.y1dataFiltered>app.ThresholdChemicals);
               
               app.Frequency_T1=app.x1data(idx,:);
               app.Intensity_T1=app.y1dataFiltered(idx,:);
               
               % now get the median of the peaks and filter by less than median
               percentile_limit = 25;
               med_thresh = prctile(app.Intensity_T1, percentile_limit);
               
               disp(sprintf('Prctile is %.2d======',med_thresh));
               
               % overwrite idx here for the peaks above thresh
               idx=( app.Intensity_T1 >= med_thresh );
               
               app.Frequency_T1=app.Frequency_T1(idx,:);
               app.Intensity_T1=app.Intensity_T1(idx,:);
               
%                Commenting out the plotting so that is faster for
%                simulations
%                pan(app.UIAxes2,'on');
%                zoom(app.UIAxes2,'on');
%                
%                plot(app.UIAxes2,app.x1data,app.y1data,'b');
%                hold(app.UIAxes2,'on');
%                plot(app.UIAxes2,app.x1data,app.y1dataFiltered,'r');
%                
%                plot(app.UIAxes2,app.Frequency_T1, app.Intensity_T1,'k*');
%                
%                xlim(app.UIAxes2,[ min(app.x1data), max(app.x1data)]);
%                hold(app.UIAxes2,'off');
               
               %app.F1Name=app.fName;
               %disp('  Table Created!')
                   
                   for z=1:(length(app.JPLGTListBoxtab2))
                       app.Plistboxvalue2=app.JPLGTListBoxtab2{z};
                       
                       filenames=strsplit(app.Plistboxvalue2);
                       
                       [~,app.F1Name,ext]=fileparts(filenames{1});
                       
                       %[app.x2data, app.y2data] = app.load_filetab22(filenames);
                       
                       tmp = app.JPLGroundTruth{app.cell2index(app.JPLFilesList, filenames{1} )};
                       app.x2data = tmp(:,1);
                       app.y2data = tmp(:,2);
                       
                       app.FRange2=2; %MHz
                       app.Delta_F2=0.0488; %Calculated by observation of sample rate in Ivan's sample data
                       app.NBW2=floor(app.FRange2/app.Delta_F2);
                       eqvars=0;
                       eqvarsT1=0;
                       eqvarsT2=0;
                       eqvarsT3=0;
                       eqvarsT4=0;
                       eqvarsT5=0;
                     
                       
                      disp('  Sorting Values...')
                       app.y2data = app.y2data(app.x2data>=app.Interval_A & app.x2data<=app.Interval_B);
                       app.x2data = app.x2data(app.x2data>=app.Interval_A & app.x2data<=app.Interval_B);
                       app.y2data = exp(app.y2data);
                       
%                        app.x2_upsampled=app.Interval_A:0.0488:app.Interval_B;
%                        app.y2_upsampled=zeros(size(app.x2_upsampled));
%                       
%                        for ie= 1:length(app.x2data)
%                            idx=find(app.x2_upsampled<app.x2data(ie),1,'last');
%                            app.y2_upsampled(idx)=app.y2data(ie);
%                        end
%                        app.ThresholdGT = app.ThresholdEditField_2.Value;
                       Top10=prctile(app.y2data, percentile_limit);
                       
                       %Top10=(max(app.y2_upsampled))*1/100; %creating a threshold based on the 10% of the maximum of the maximum peak.
                       disp(sprintf('\t Prctile is %.2d======',Top10))
                                                                     
                       %ngh2=strel('line',app.NBW,90);
                       %app.img2=imdilate(app.y2_upsampled,ngh2);
                       
                       %idx=( app.y2_upsampled==app.img2 & app.y2_upsampled>Top10);
                       %idx=( app.y2_upsampled>Top10);
                       
                                              
%                        app.Frequency_T2=app.x2_upsampled(idx);
%                        app.Intensity_T2=app.y2_upsampled(idx);
                       
                       idx_eric = app.y2data>Top10;
                       app.Frequency_T2=app.x2data(idx_eric);
                       app.Intensity_T2=app.y2data(idx_eric);
                       
                       
                       %Commenting plotting for simulation reasons
                       
%                        pan(app.UIAxes2_2,'on');
%                        zoom(app.UIAxes2_2,'on');
%                        
%                        
%                        plot(app.UIAxes2_2,app.x2_upsampled, app.y2_upsampled,'b'); 
%                        hold(app.UIAxes2_2,'on');
%                        plot(app.UIAxes2_2,app.Frequency_T2, app.Intensity_T2,'k*');
%                        %disp('  Done Plotting')
%                        
%                        xlim(app.UIAxes2_2,[ min(app.x2data), max(app.x2data)]);
%                        
                       %T=table(app.Frequency_T2.',app.Intensity_T2.','VariableNames',{'Frequency','Intensity',});
                       %disp('  Table Created!')
                     
                       tolerance_thresh1 = 1e-06; % In april this was: 1e-6
                       tolerance_thresh2 = 5e-07;
                       tolerance_thresh3 = 1.6e-05;
                       tolerance_thresh4 = 5e-08;
                       tolerance_thresh5 = 1.8e-05;
                       
                       
                       %  eqvars(ii)=ismember(app.Frequency_T2(ii),app.Frequency_T1); % finding peaks that are in both samples at EXACT locations
                       eqvarsT1=ismembertol(app.Frequency_T2,app.Frequency_T1,tolerance_thresh1); %Finding peaks that are in both samples at frequency +_ threshold value
                       eqvarsT2=ismembertol(app.Frequency_T2,app.Frequency_T1,tolerance_thresh2);
                       eqvarsT3=ismembertol(app.Frequency_T2,app.Frequency_T1,tolerance_thresh3);
                       eqvarsT4=ismembertol(app.Frequency_T2,app.Frequency_T1,tolerance_thresh4);
                       eqvarsT5=ismembertol(app.Frequency_T2,app.Frequency_T1,tolerance_thresh5);
                       
                         
                       %Meeting changed the second matrix of intensity T2
                       %pctg=(sum(eqvars)/size(app.Intensity_T2,1))*100; %Finding % that are in both samples at frequency for EXACT locations
                       
                       pctg1=sum(eqvarsT1)/size(eqvarsT1,1)*100; %Finding % that are in both samples at frequency +_ threshold value                      
                       pctg2=sum(eqvarsT2)/size(eqvarsT2,1)*100;
                       pctg3=sum(eqvarsT3)/size(eqvarsT3,1)*100;
                       pctg4=sum(eqvarsT4)/size(eqvarsT4,1)*100;
                       pctg5=sum(eqvarsT5)/size(eqvarsT5,1)*100;
                       
                       
                       if strfind(app.fName,app.F1Name)
                             pred(d,z)=1;
                       else
                             pred(d,z)=0;
                               
                       end
                       %resp(d,z)=pctg;


                       load('DATAFILE.mat');
                       
                       save('DATAFILEBACKUP.mat','T3');    
                       
                         %T2(end+1,:) = table(app.fName, app.F1Name, app.noise_intensity, app.Std,app.Mean,pctg1,pctg2,pctg3,pctg4,pctg5,'VariableNames',varNames);
                        T3(end+1,:) = table(string(app.fName), string(app.F1Name), app.noise_intensity, app.Std,app.Mean,tolerance_thresh1,pctg1,tolerance_thresh2,pctg2,tolerance_thresh3,pctg3,tolerance_thresh4,pctg4,tolerance_thresh5,pctg5,pred(d,z),offset_value,size(app.Frequency_T1,1), size(app.Frequency_T2,1),'VariableNames',varNames2);
                           
                       
                       save('DATAFILE.mat','T3','-append');

                      
                       %writetable(T3,'output.txt','delimiter',' ');
                      
                       
%                        
%                        figure()
%                        
%                        ax=[];
%                        ax(end+1)=subplot(2,1,1);
%                        plot(app.x1data,app.y1dataFiltered,'r');
%                        hold on;
%                        plot(app.Frequency_T1, app.Intensity_T1,'k*');
%                        xlabel('Frequency MHz');
%                        ylabel('Intensity (Arb unit)');
%                        title(sprintf('Sample Data Percentage for file:   %s PCTG1 %.2f,PCTG2 %.2f, PCTG3 %.2f,PCTG4 %.2f,PCTG4 %.2f,',app.fName, pctg1,pctg2,pctg3,pctg4,pctg5));
%                         hold off;
                       
%                        % DISPLAY RESULTS
                       disp(sprintf('\t %s Data percentage: %.2f, %.2f, %.2f %.2f, %.2f',app.F1Name, pctg1,pctg2,pctg3,pctg4,pctg5));
                       disp(sprintf('\t Peaks found in simulation and JPL: %.2f, %.2f, ',size(app.Frequency_T1,1), size(app.Frequency_T2,1)));

%                        ax(end+1)=subplot(2,1,2);
%                        plot(app.Frequency_T2(logical(eqvarsT1)),app.Intensity_T2(logical(eqvarsT1)),'r');
%                        hold on;
%                        plot(app.Frequency_T2, app.Intensity_T2,'k*');
%                        %disp('  Done Plotting')
%                        xlabel('Frequency MHz');
%                        ylabel('Intensity (Arb unit)');
%                        title(sprintf('JPL Library of:  %s', app.F1Name));
%                         hold off;
%                         
%                        linkaxes(ax,'x');
                                
                       z=z+1;
                              
                   end
                d=d+1;
             
          
%          
            end
              
%            
%             rpt = Report('output','pdf');
%                 
%                 % Add content to container (required)
%                 % Types of content added here: title
%                 % page and table of contents reporters
%                 titlepg = TitlePage;
%                 titlepg.Title = 'Test Results';
%                 titlepg.Author = 'Test A';
%                 add(rpt,titlepg);
%                 add(rpt,TableOfContents);
%                 
%                 t=Table(T2);
%                 t.Style = {RowHeight('0.5in')};
%                 t.Width = '50%';
%                 t.Border = 'solid';
%                 t.BorderWidth = '1px';
%                 t.ColSep = 'solid';
%                 t.ColSepWidth = '0.5';
%                 t.RowSep = 'solid';
%                 t.RowSepWidth = '0.5';
%                 t.TableEntriesStyle = {FontFamily('Arial'),Width('0.9in'),Color('black')};
%                 t.TableEntriesHAlign = 'center';
%                 t.TableEntriesVAlign = 'middle';
%                 
%                 
%                 % Add content to report sections (optional)
%                 % Text and formal image added to chapter
%                 chap = Chapter('Simulation 1');
%                 add(chap,'Results:');
%                 add(chap,t)
%                 
%                 
%                 %add(chap,FormalImage('Image',...
%                 %which('b747.jpg'),'Height','5in',...
%                  %   'Width','5in','Caption','Boeing 747'));
%                 add(rpt,chap);
%                 
%                 
%                 % Close the report (required)
%                 close(rpt);
%                 % Display the report (optional)
%                 rptview(rpt);
                
       
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)

            app.listboxvalue2 = app.ChemicalListBox2.Value;
            app.ChemicalListtab2= {};
            for i=1:length(app.listboxvalue2)
                app.ChemicalListtab2=[app.listboxvalue2{i},' ',app.ChemicalListtab2];
                i=i+1;
            end
            
            
            app.ChemicalListtab22{app.so2}=table(app.po2,app.ChemicalListtab2,'VariableNames',{'po','names'});
            app.ChemicalSelectionListBoxtab2{app.po2}=([app.ChemicalListtab2{:}]);


            app.ChemicalSelectionListBox2.Items{app.po2}=app.ChemicalSelectionListBoxtab2{app.po2};
            app.po2=app.po2+1;
        end

        % Button pushed function: AddButton_2
        function AddButton_2Pushed(app, event)
            
            app.JPLlistboxvalue=app.JPLGroundTruthListBox.Value;
            app.JPLGTList= {};
            for i=1:length(app.JPLlistboxvalue)
                app.JPLGTList=[app.JPLlistboxvalue{i},' ',app.JPLGTList];
               
                i=i+1;
                
            end
            
            
            app.JPlGTList2{app.so3}=table(app.po3,app.JPLGTList,'VariableNames',{'po','names'});
            app.JPLGTListBoxtab2{app.po3}=([app.JPLGTList{:}]);


            app.GTSelectionListBox.Items{app.po3}=app.JPLGTListBoxtab2{app.po3};
            app.po3=app.po3+1;
        end

        % Button pushed function: ReportButton
        function ReportButtonPushed(app, event)
%             import mlreportgen.report.*
%             import mlreportgen.dom.*
%             
            
%             
%             rpt = Report('Simple', 'pdf')
%             open (rpt)
%             
%             T= Text ("Hello World")
%             T.Bold=true;
%             T.FontSize="32pt"
%             
%             add(rpt, T)
%             surf (peaks)
            close (D)
%             add(rpt, TitlePage('Title', 'Midterm Report'));
%             add(rpt, TableOfContents);
%             ch = Chapter('Title', 'Surfaces');
%             walls = {{'Wall 1 Length: ', app.Wall1Length.Value} ...
%                 {'Wall 2 Length: ', app.wall2Length.Value}}'
%             add(ch, BaseTable('Title', 'Wall Data', 'Content', walls));
%             add(rpt, ch);
%             close(rpt);
%             rptview(rpt.OutputPath);

%             D=Document('FromTemplate','docx','Template.dotx')
%             open(D)
%             moveToNextHole(D)
%             append(D,"Hello World")
%             
%             moveToNextHole(D)
%            % T= readtable('patients..dat')
%             
%             append(D,MATLABTable(T))
%             close(D)
%             rptview(D)
%             
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.UIAxes = uiaxes(app.SimulationTab);
            title(app.UIAxes, 'Sample Data')
            xlabel(app.UIAxes, 'Frequency (MHz)')
            ylabel(app.UIAxes, 'Intensity (Arb Unit)')
            app.UIAxes_3 = uiaxes(app.SimulationTab);
            title(app.UIAxes_3, 'Chemical selected with added spikes and noise')
            xlabel(app.UIAxes_3, 'Frequency (MHz)')
            ylabel(app.UIAxes_3, 'Intensity (Arb Unit)')
            app.RefreshButton = uibutton(app.SimulationTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @refreshplot, true);
            app.RefreshButton.Text = 'Refresh';
            app.ParametersPanel_2 = uipanel(app.SimulationTab);
            app.ParametersPanel_2.Title = 'Parameters';
            app.NoiseIntensitySliderLabel = uilabel(app.ParametersPanel_2);
            app.NoiseIntensitySliderLabel.Text = {'Noise'; 'Intensity'};
            app.NoiseIntensitySlider = uislider(app.ParametersPanel_2);
            app.NoiseIntensitySlider.Limits = [0 0.2];
            app.NoiseIntensitySlider.ValueChangedFcn = createCallbackFcn(app, @NoiseIntensitySliderValueChanged, true);
            app.BandwidthLabel = uilabel(app.ParametersPanel_2);
            app.BandwidthLabel.Text = 'Bandwidth';
            app.BandwidthSlider = uislider(app.ParametersPanel_2);
            app.BandwidthSlider.Limits = [0 25];
            app.BandwidthSlider.MajorTicks = [0 2 4 6 8 10 12 14 16 18 20 22 25];
            app.BandwidthSlider.ValueChangedFcn = createCallbackFcn(app, @BandwidthSliderValueChanged, true);
            app.BandwidthSlider.Value = 16;
            app.UIAxes_2 = uiaxes(app.ParametersPanel_2);
            title(app.UIAxes_2, 'Sample Peak')
            xlabel(app.UIAxes_2, 'Frequency (MHz)')
            ylabel(app.UIAxes_2, 'Intensity (Arb Unit)')
            app.SelectthefrequencyintervalLabel = uilabel(app.ParametersPanel_2);
            app.SelectthefrequencyintervalLabel.Text = 'Select the frequency interval:';
            app.PeakParametersLabel = uilabel(app.ParametersPanel_2);
            app.PeakParametersLabel.Text = 'Peak Parameters:';
            app.SinewaveparametersLabel = uilabel(app.ParametersPanel_2);
            app.SinewaveparametersLabel.Text = 'Sinewave parameters';
            app.MHzLabel = uilabel(app.ParametersPanel_2);
            app.MHzLabel.Text = 'MHz';
            app.MHzLabel_2 = uilabel(app.ParametersPanel_2);
            app.MHzLabel_2.Text = 'MHz';
            app.IntervalAEditFieldLabel = uilabel(app.ParametersPanel_2);
            app.IntervalAEditFieldLabel.Text = 'Interval A';
            app.IntervalAEditField = uieditfield(app.ParametersPanel_2, 'numeric');
            app.IntervalAEditField.ValueChangedFcn = createCallbackFcn(app, @ParametersValueChanged, true);
            app.IntervalAEditField.Value = 500;
            app.IntervalBEditFieldLabel = uilabel(app.ParametersPanel_2);
            app.IntervalBEditFieldLabel.Text = 'Interval B';
            app.IntervalBEditField = uieditfield(app.ParametersPanel_2, 'numeric');
            app.IntervalBEditField.ValueChangedFcn = createCallbackFcn(app, @ParametersValueChanged, true);
            app.IntervalBEditField.Value = 400000;
            app.PeriodEditFieldLabel = uilabel(app.ParametersPanel_2);
            app.PeriodEditFieldLabel.Text = 'Period';
            app.PeriodEditField = uieditfield(app.ParametersPanel_2, 'numeric');
            app.PeriodEditField.ValueChangedFcn = createCallbackFcn(app, @ParametersValueChanged, true);
            app.PeriodEditField.Value = 0.0004;
            app.MeanEditFieldLabel = uilabel(app.ParametersPanel_2);
            app.MeanEditFieldLabel.Text = 'Mean';
            app.MeanValueEdit = uieditfield(app.ParametersPanel_2, 'numeric');
            app.MeanValueEdit.ValueChangedFcn = createCallbackFcn(app, @ParametersValueChanged, true);
            app.MeanValueEdit.Value = 1;
            app.StandardDeviationEditFieldLabel = uilabel(app.ParametersPanel_2);
            app.StandardDeviationEditFieldLabel.Text = 'Standard Deviation';
            app.StandardDeviationEdit = uieditfield(app.ParametersPanel_2, 'numeric');
            app.StandardDeviationEdit.ValueChangedFcn = createCallbackFcn(app, @ParametersValueChanged, true);
            app.AmplitudeEditFieldLabel = uilabel(app.ParametersPanel_2);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEdit = uieditfield(app.ParametersPanel_2, 'numeric');
            app.AmplitudeEdit.ValueChangedFcn = createCallbackFcn(app, @ParametersValueChanged, true);
            app.AddParametersButton = uibutton(app.ParametersPanel_2, 'push');
            app.AddParametersButton.ButtonPushedFcn = createCallbackFcn(app, @AddParametersButtonPushed, true);
            app.AddParametersButton.Text = 'Add';
            app.CorrelationButton = uibutton(app.SimulationTab, 'push');
            app.CorrelationButton.ButtonPushedFcn = createCallbackFcn(app, @CorrelationButtonPushed, true);
            app.CorrelationButton.Text = 'Correlation';
            app.SimulationPadPanel = uipanel(app.SimulationTab);
            app.SimulationPadPanel.Title = 'Simulation Pad';
            app.SimulateButton = uibutton(app.SimulationPadPanel, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.ChemicalSelectionListBoxLabel = uilabel(app.SimulationPadPanel);
            app.ChemicalSelectionListBoxLabel.Text = 'Chemical Selection ';
            app.ChemicalSelectionListBox = uilistbox(app.SimulationPadPanel);
            app.ChemicalSelectionListBox.Items = {};
            app.ChemicalSelectionListBox.Value = {};
            app.SimulationNumberEditFieldLabel = uilabel(app.SimulationPadPanel);
            app.SimulationNumberEditFieldLabel.Text = 'Simulation Number';
            app.SimulationNumber = uieditfield(app.SimulationPadPanel, 'numeric');
            app.SimulationNumber.Limits = [1 Inf];
            app.SimulationNumber.Value = 1;
            app.ParametersSelectionListBoxLabel = uilabel(app.SimulationPadPanel);
            app.ParametersSelectionListBoxLabel.Text = 'Parameters Selection';
            app.ParametersSelectionListBox = uilistbox(app.SimulationPadPanel);
            app.ParametersSelectionListBox.Items = {};
            app.ParametersSelectionListBox.Value = {};
            app.AutogenerateButton = uibutton(app.SimulationPadPanel, 'push');
            app.AutogenerateButton.ButtonPushedFcn = createCallbackFcn(app, @AutogenerateButtonPushed, true);
            app.AutogenerateButton.Text = 'Auto-generate';
            app.ChemicalSelectionPanel = uipanel(app.SimulationTab);
            app.ChemicalSelectionPanel.Title = 'Chemical Selection';
            app.ChemicalSelectionButton = uibutton(app.ChemicalSelectionPanel, 'push');
            app.ChemicalSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @ChemicalSelectionButtonPushed, true);
            app.ChemicalSelectionButton.Text = 'Add';
            app.ImportButton = uibutton(app.ChemicalSelectionPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ChemicalListBoxLabel = uilabel(app.ChemicalSelectionPanel);
            app.ChemicalListBoxLabel.Text = 'Chemical';
            app.ChemicalListBox = uilistbox(app.ChemicalSelectionPanel);
            app.ChemicalListBox.Items = {};
            app.ChemicalListBox.Multiselect = 'on';
            app.ChemicalListBox.Value = {};
            app.CorrelationTab = uitab(app.TabGroup);
            app.CorrelationTab.Title = 'Correlation';
            app.UIAxes2 = uiaxes(app.CorrelationTab);
            title(app.UIAxes2, 'SAMPLE DATA')
            xlabel(app.UIAxes2, 'Frequency (MHz)')
            ylabel(app.UIAxes2, 'Intensity (Arb Unit)')
            app.UIAxes2_2 = uiaxes(app.CorrelationTab);
            title(app.UIAxes2_2, 'JPL Library')
            xlabel(app.UIAxes2_2, 'Frequency (MHz)')
            ylabel(app.UIAxes2_2, 'Intensity (Arb Unit)')
            app.SimulationPadPanel_2 = uipanel(app.CorrelationTab);
            app.SimulationPadPanel_2.Title = 'Simulation Pad';
            app.ChemicalSelectionLabel = uilabel(app.SimulationPadPanel_2);
            app.ChemicalSelectionLabel.Text = 'Chemical Selection';
            app.ChemicalSelectionListBox2 = uilistbox(app.SimulationPadPanel_2);
            app.ChemicalSelectionListBox2.Items = {};
            app.ChemicalSelectionListBox2.Value = {};
            app.GroundTruthSelectionLabel = uilabel(app.SimulationPadPanel_2);
            app.GroundTruthSelectionLabel.Text = 'Ground Truth Selection';
            app.GTSelectionListBox = uilistbox(app.SimulationPadPanel_2);
            app.GTSelectionListBox.Items = {};
            app.GTSelectionListBox.Value = {};
            app.CorrelationPeaks = uibutton(app.SimulationPadPanel_2, 'push');
            app.CorrelationPeaks.ButtonPushedFcn = createCallbackFcn(app, @CorrelationPeaksPushed, true);
            app.CorrelationPeaks.Text = 'Fitting';
            app.RefreshButton_2 = uibutton(app.SimulationPadPanel_2, 'push');
            app.RefreshButton_2.ButtonPushedFcn = createCallbackFcn(app, @refreshplot, true);
            app.RefreshButton_2.Text = 'Refresh';
            app.ReportButton = uibutton(app.SimulationPadPanel_2, 'push');
            app.ReportButton.ButtonPushedFcn = createCallbackFcn(app, @ReportButtonPushed, true);
            app.ReportButton.Text = 'Report';
            app.SelectionPanel = uipanel(app.CorrelationTab);
            app.SelectionPanel.Title = 'Selection ';
            app.AddButton_2 = uibutton(app.SelectionPanel, 'push');
            app.AddButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddButton_2Pushed, true);
            app.AddButton_2.Text = 'Add';
            app.JPLGroundTruthListBoxLabel = uilabel(app.SelectionPanel);
            app.JPLGroundTruthListBoxLabel.Text = 'JPL (Ground Truth)';
            app.JPLGroundTruthListBox = uilistbox(app.SelectionPanel);
            app.JPLGroundTruthListBox.Items = {};
            app.JPLGroundTruthListBox.Multiselect = 'on';
            app.JPLGroundTruthListBox.Value = {};
            app.AddButton = uibutton(app.SelectionPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.ChemicalListBox_2Label = uilabel(app.SelectionPanel);
            app.ChemicalListBox_2Label.Text = 'Chemical';
            app.ChemicalListBox2 = uilistbox(app.SelectionPanel);
            app.ChemicalListBox2.Items = {};
            app.ChemicalListBox2.Multiselect = 'on';
            app.ChemicalListBox2.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Results1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end